classdef read_dataset < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        btn_gnss       matlab.ui.control.Button
        btn_imu        matlab.ui.control.Button
        LOGTextArea    matlab.ui.control.TextArea
        btn_clear      matlab.ui.control.Button
        btn_read_file  matlab.ui.control.Button
    end

    
    properties (Access = private)
        data =0; % data
        defaultPath =''; % defaultPath
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button pushed function: btn_read_file
        function btn_read_filePushed(app, event)

            % 打开文件选择器对话框
            if isempty(app.defaultPath)
                [filename, pathname] = uigetfile('*.csv');
            else
                [filename, pathname] = uigetfile(app.defaultPath, '*.csv');
            end 
            app.defaultPath =fullfile(pathname, filename);
            
            if ischar(filename)
                app.LOGTextArea.Value = sprintf('%s\r\n', string(app.LOGTextArea.Value), fullfile(pathname, filename));

                fileID = fopen('print_log.txt', 'w');
                fclose(fileID);
                diary('print_log.txt')
                diary on;

                app.data = csv2mat_1(fullfile(pathname, filename));
                diary off;
                output=fileread('print_log.txt');
                app.LOGTextArea.Value = sprintf('%s%s\r\n', string(app.LOGTextArea.Value), output);
            end
            
        end

        % Button pushed function: btn_clear
        function btn_clearPushed(app, event)
            app.LOGTextArea.Value = "";
        end

        % Button pushed function: btn_imu
        function btn_imuButtonPushed(app, event)
            N =length(app.data.imu.gyr);
            x =1:N;
            figure;
            
            subplot(2, 1, 1);
            plot(x, app.data.imu.gyr(: ,1), x, app.data.imu.gyr(: ,2), x, app.data.imu.gyr(: ,3));
            title("角速度");
            legend("X轴", "Y轴", "Z轴");
  
            subplot(2, 1, 2);
            plot(x, app.data.imu.acc(: ,1), x, app.data.imu.acc(: ,2), x, app.data.imu.acc(: ,3));
            title("加速度");
            legend("X轴", "Y轴", "Z轴");            
        end

        % Button pushed function: btn_gnss
        function btn_gnssButtonPushed(app, event)
            N =length(app.data.gnss.lla);
            x =1:N;
            figure;
            
            subplot(2, 1, 1);
            plot(x, app.data.gnss.lla(: ,1), x, app.data.gnss.lla(: ,2), x, app.data.gnss.lla(: ,3));
            title("GNSS位置");
            legend("经度", "纬度", "高度");
  
            subplot(2, 1, 2);
            plot(x, app.data.gnss.vel(: ,1), x, app.data.gnss.vel(: ,2), x, app.data.gnss.vel(: ,3));
            title("GNSS速度");
            legend("东向速度", "北向速度", "天向速度"); 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = '数据导入';
            app.btn_read_file = uibutton(app.UIFigure, 'push');
            app.btn_read_file.ButtonPushedFcn = createCallbackFcn(app, @btn_read_filePushed, true);
            app.btn_read_file.Text = '选择csv文件';
            app.btn_clear = uibutton(app.UIFigure, 'push');
            app.btn_clear.ButtonPushedFcn = createCallbackFcn(app, @btn_clearPushed, true);
            app.btn_clear.Text = 'Clear';
            app.LOGTextArea = uitextarea(app.UIFigure);
            app.LOGTextArea.Editable = 'off';
            app.btn_imu = uibutton(app.UIFigure, 'push');
            app.btn_imu.ButtonPushedFcn = createCallbackFcn(app, @btn_imuButtonPushed, true);
            app.btn_imu.Text = 'IMU原始数据';
            app.btn_gnss = uibutton(app.UIFigure, 'push');
            app.btn_gnss.ButtonPushedFcn = createCallbackFcn(app, @btn_gnssButtonPushed, true);
            app.btn_gnss.Text = 'GNSS原始数据';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = read_dataset

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end